classdef MosquitoClassifier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UploadImageButton               matlab.ui.control.Button
        AdultClassifierOptionsButtonGroup  matlab.ui.container.ButtonGroup
        GenderButton                    matlab.ui.control.RadioButton
        SpeciesButton                   matlab.ui.control.RadioButton
        ClassifyImageButton             matlab.ui.control.Button
        ChooseClassifierSwitchLabel     matlab.ui.control.Label
        ChooseClassifierSwitch          matlab.ui.control.Switch
        ClassifierOutputEditFieldLabel  matlab.ui.control.Label
        ClassifierOutputEditField       matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
        PupaeClassifierOptionsButtonGroup  matlab.ui.container.ButtonGroup
        GenderButton_2                  matlab.ui.control.RadioButton
        Image                           matlab.ui.control.Image
        ChoosegenderbeforespeciesclassificationLabel  matlab.ui.control.Label
        ChoosegenderbeforespeciesclassificationSwitch  matlab.ui.control.Switch
        Image2                          matlab.ui.control.Image
        instructions                    matlab.ui.control.Label
        LoadClassifierButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        PupaeBool = true ;
        AdultBool = true ;
        GenderBool = true ;
        SpeciesBool = true ;
        Male = true;
        Female = true;
        CurrentImage ;
        netPupae ;
        netAdultGender ;
        netAdultSpeciesMale ;
        netAdultSpeciesFemale ;
        resizeImage ;
        Net;
        filePath;
    end
    
    methods (Access = private)
        
        
        function results = setUpGoogleNetPupaeGender(app)
            app.CurrentImage = imresize(app.CurrentImage, [224 224]);
            app.Net = "GooglePupaeGender";
            
        end
        
        function results = SetUpNasnetAdultSpeciesFemale(app)
             app.CurrentImage = imresize(app.CurrentImage, [224 224]);
            app.Net = "NasnetAdultSpecies";
        end
        function results = SetUpNasnetAdultSpeciesMale(app)
             app.CurrentImage = imresize(app.CurrentImage, [224 224]);
            app.Net = "NasnetAdultSpecies";
        end
        
        function results = SetUpGoogleNetAdultGender(app)
             app.CurrentImage = imresize(app.CurrentImage, [224 224]);
            app.Net = "GoogleAdultGender";
        end
%       for nasnet  [331 331]
       
        
        function results =loadPupaeNetFuntion(app)
            app.netPupae = load("netPupae.mat");
            app.netPupae = app.netPupae.net;
            
        end
        function results =loadAdultGenderNetFunction(app)
            app.netAdultGender = load("adultGenderNet.mat");
            app.netAdultGender = app.netAdultGender.net;
            
        end
        function results =loadAdultSpeciesFemaleNetFunction(app)
            app.netAdultSpeciesFemale = load("netGoogleAdultSpeciesFemale2.mat");
            app.netAdultSpeciesFemale = app.netAdultSpeciesFemale.net;
            
        end
        function results =loadAdultSpeciesMaleNetFunction(app)
            app.netAdultSpeciesMale = load("netGoogleAdultSpeciesMale.mat");
            app.netAdultSpeciesMale = app.netAdultSpeciesMale.net;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.loadPupaeNetFuntion(); 
        app.loadAdultGenderNetFunction();
        app.loadAdultSpeciesFemaleNetFunction();
        app.loadAdultSpeciesMaleNetFunction();   
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
        [Filename,Filepath] = uigetfile ({'*.jpeg';'*.jpg'},'File Selector'); 
        ImageName = [Filepath, Filename];
        ImageFile = imread(ImageName);
        imshow(ImageFile, 'Parent', app.UIAxes); 
        app.CurrentImage = ImageFile;
        app.filePath = ImageName;
        
        
        end

        % Value changed function: ChooseClassifierSwitch
        function ChooseClassifierSwitchValueChanged(app, event)
            value = app.ChooseClassifierSwitch.Value;
            if value == "Adult"
            app.AdultClassifierOptionsButtonGroup.Visible = true; 
            app.PupaeClassifierOptionsButtonGroup.Visible  = false;
            app.AdultBool = true;
            app.PupaeBool = false;
            app.GenderBool = true;
            app.SpeciesBool = false;
            app.ChoosegenderbeforespeciesclassificationSwitch.Visible = false;
            app.ChoosegenderbeforespeciesclassificationLabel.Visible = false;
            %% set up for Adult gender selection
            app.AdultClassifierOptionsButtonGroup.SelectedObject = app.GenderButton;
            app.GenderBool = true;
            app.SpeciesBool = false;
            app.ChoosegenderbeforespeciesclassificationSwitch.Visible = false;
            app.ChoosegenderbeforespeciesclassificationLabel.Visible = false;
            
            elseif value == "Pupae"       
            app.PupaeClassifierOptionsButtonGroup.Visible  = true;
            app.AdultClassifierOptionsButtonGroup.Visible = false; 
            app.ChoosegenderbeforespeciesclassificationSwitch.Visible = false;
            app.ChoosegenderbeforespeciesclassificationLabel.Visible = false;
            app.AdultBool = false;
            app.PupaeBool = true;


            end    
        end

        % Selection changed function: 
        % AdultClassifierOptionsButtonGroup
        function AdultClassifierOptionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.AdultClassifierOptionsButtonGroup.SelectedObject;
            if selectedButton == app.GenderButton
                app.GenderBool = true;
                app.SpeciesBool = false;
                
                app.ChoosegenderbeforespeciesclassificationSwitch.Visible = false;
                app.ChoosegenderbeforespeciesclassificationLabel.Visible = false;
                
            elseif selectedButton == app.SpeciesButton
                app.GenderBool = false;
                app.SpeciesBool = true;
                app.ChoosegenderbeforespeciesclassificationSwitch.Visible = true;
                app.ChoosegenderbeforespeciesclassificationLabel.Visible = true;
                %% load for male
                app.ChoosegenderbeforespeciesclassificationSwitch.Value = 'Male';
                app.Male = true;
                app.Female =false;
                
            end
            
            
        end

        % Button pushed function: ClassifyImageButton
        function ClassifyImageButtonPushed(app, event)
            if app.AdultBool == true && app.GenderBool == true
            app.ClassifierOutputEditField.Value = "Adult + Gender";
            app.SetUpGoogleNetAdultGender();
            [labels,err_test] = classify(app.netAdultGender, app.CurrentImage);
            sMax = max(err_test);
            frame = insertText(app.CurrentImage, [0 5], [char(labels) ':' num2str(sMax)]);
            imshow(frame, 'Parent', app.UIAxes); 
            app.ClassifierOutputEditField.Value = [char(labels) ':' num2str(sMax)];
            
            elseif (app.AdultBool == true) && (app.SpeciesBool == true) && (app.Male == true)
            app.ClassifierOutputEditField.Value = "Adult + Species + Male"; 
            app.SetUpNasnetAdultSpeciesMale();
            [labels,err_test] = classify(app.netAdultSpeciesMale, app.CurrentImage);
            sMax = max(err_test);
            frame = insertText(app.CurrentImage, [0 5], [char(labels) ':' num2str(sMax)]);
            imshow(frame, 'Parent', app.UIAxes); 
            app.ClassifierOutputEditField.Value = [char(labels) ':' num2str(sMax)];
            
            elseif (app.AdultBool == true) && (app.SpeciesBool == true) && (app.Female == true)
            app.ClassifierOutputEditField.Value = "Adult + Species + Female"; 
            app.SetUpNasnetAdultSpeciesFemale();
            [labels,err_test] = classify(app.netAdultSpeciesFemale, app.CurrentImage);
            sMax = max(err_test);
            frame = insertText(app.CurrentImage, [0 5], [char(labels) ':' num2str(sMax)]);
            imshow(frame, 'Parent', app.UIAxes); 
            app.ClassifierOutputEditField.Value = [char(labels) ':' num2str(sMax)];
            
            elseif app.PupaeBool == true
            app.ClassifierOutputEditField.Value = "Pupae + Gender";
            app.setUpGoogleNetPupaeGender();
            [labels,err_test] = classify(app.netPupae, app.CurrentImage);
            sMax = max(err_test);
            frame = insertText(app.CurrentImage, [0 5], [char(labels) ':' num2str(sMax)]);
            imshow(frame, 'Parent', app.UIAxes); 
            app.ClassifierOutputEditField.Value = [char(labels) ':' num2str(sMax)];
                
            end
         
        end

        % Value changed function: 
        % ChoosegenderbeforespeciesclassificationSwitch
        function ChoosegenderbeforespeciesclassificationSwitchValueChanged(app, event)
            value = app.ChoosegenderbeforespeciesclassificationSwitch.Value;
             if value ==  "Male"
                app.Male = true;
                app.Female =false;
            elseif value == "Female"
                app.Male = false;
                app.Female = true;
            end
            
        end

        % Button pushed function: LoadClassifierButton
        function LoadClassifierButtonPushed(app, event)
            app.PupaeClassifierOptionsButtonGroup.Visible  = true;
            app.AdultClassifierOptionsButtonGroup.Visible = false; 
            app.ChoosegenderbeforespeciesclassificationSwitch.Visible = false;
            app.ChoosegenderbeforespeciesclassificationLabel.Visible = false;
            app.AdultBool = false;
            app.PupaeBool = true;
            app.instructions.Visible = false;
            app.Image2.Visible =false;
            app.LoadClassifierButton.Visible = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.AdultClassifierOptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.AdultClassifierOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AdultClassifierOptionsButtonGroupSelectionChanged, true);
            app.AdultClassifierOptionsButtonGroup.Title = 'Adult Classifier Options';
            app.GenderButton = uiradiobutton(app.AdultClassifierOptionsButtonGroup);
            app.GenderButton.Text = 'Gender';
            app.GenderButton.Value = true;
            app.SpeciesButton = uiradiobutton(app.AdultClassifierOptionsButtonGroup);
            app.SpeciesButton.Text = 'Species';
            app.ClassifyImageButton = uibutton(app.UIFigure, 'push');
            app.ClassifyImageButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyImageButtonPushed, true);
            app.ClassifyImageButton.Text = 'Classify Image';
            app.ChooseClassifierSwitchLabel = uilabel(app.UIFigure);
            app.ChooseClassifierSwitchLabel.Text = 'Choose Classifier';
            app.ChooseClassifierSwitch = uiswitch(app.UIFigure, 'slider');
            app.ChooseClassifierSwitch.Items = {'Pupae', 'Adult'};
            app.ChooseClassifierSwitch.ValueChangedFcn = createCallbackFcn(app, @ChooseClassifierSwitchValueChanged, true);
            app.ChooseClassifierSwitch.Value = 'Pupae';
            app.ClassifierOutputEditFieldLabel = uilabel(app.UIFigure);
            app.ClassifierOutputEditFieldLabel.Text = 'Classifier Output:';
            app.ClassifierOutputEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image Loaded')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.PupaeClassifierOptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.PupaeClassifierOptionsButtonGroup.Title = 'Pupae Classifier Options';
            app.GenderButton_2 = uiradiobutton(app.PupaeClassifierOptionsButtonGroup);
            app.GenderButton_2.Text = 'Gender';
            app.GenderButton_2.Value = true;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'mosImage.png';
            app.ChoosegenderbeforespeciesclassificationLabel = uilabel(app.UIFigure);
            app.ChoosegenderbeforespeciesclassificationLabel.Text = {'Choose gender'; 'before species classification:'};
            app.ChoosegenderbeforespeciesclassificationSwitch = uiswitch(app.UIFigure, 'slider');
            app.ChoosegenderbeforespeciesclassificationSwitch.Items = {'Male', 'Female'};
            app.ChoosegenderbeforespeciesclassificationSwitch.ValueChangedFcn = createCallbackFcn(app, @ChoosegenderbeforespeciesclassificationSwitchValueChanged, true);
            app.ChoosegenderbeforespeciesclassificationSwitch.Value = 'Male';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'cover.jpg';
            app.instructions = uilabel(app.UIFigure);
            app.instructions.Text = {'Wits Anopheles Mosquito Classifier.'; 'Classify. jpg and .jpeg images.'; 'Classify adult anopheles mosquitos into either Gender or Species(Gambiae or Funestus).'; 'Classify Pupae into Gender.'; 'By Rian Breytenbach and Kelly Margalit.'};
            app.LoadClassifierButton = uibutton(app.UIFigure, 'push');
            app.LoadClassifierButton.ButtonPushedFcn = createCallbackFcn(app, @LoadClassifierButtonPushed, true);
            app.LoadClassifierButton.Text = 'Load Classifier';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MosquitoClassifier

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end